classdef Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrostructureGeneratorUIFigure  matlab.ui.Figure
        CostFunction1Panel              matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        TaperDropDownLabel              matlab.ui.control.Label
        TaperDropDown                   matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        ObjectStateLabelDropDownLabel   matlab.ui.control.Label
        ObjectStateLabelDropDown        matlab.ui.control.DropDown
        ObjectiveDropDownLabel          matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        ImageStateLabelDropDownLabel    matlab.ui.control.Label
        ImageStateLabelDropDown         matlab.ui.control.DropDown
        LoadTargetButton                matlab.ui.control.Button
        RangeLabel                      matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        FullLabel                       matlab.ui.control.Label
        CostFunction2Panel              matlab.ui.container.Panel
        Switch_2                        matlab.ui.control.Switch
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        TaperDropDown_2Label            matlab.ui.control.Label
        TaperDropDown_2                 matlab.ui.control.DropDown
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        ObjectStateLabelDropDown_2Label  matlab.ui.control.Label
        ObjectStateLabelDropDown_2      matlab.ui.control.DropDown
        ObjectiveDropDown_2Label        matlab.ui.control.Label
        ObjectiveDropDown_2             matlab.ui.control.DropDown
        Button_2                        matlab.ui.control.Button
        ImageStateLabelDropDown_2Label  matlab.ui.control.Label
        ImageStateLabelDropDown_2       matlab.ui.control.DropDown
        LoadTargetButton_2              matlab.ui.control.Button
        RangeLabel_2                    matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        FullLabel_2                     matlab.ui.control.Label
        CostFunction3Panel              matlab.ui.container.Panel
        Switch_3                        matlab.ui.control.Switch
        MinEditField_3Label             matlab.ui.control.Label
        MinEditField_3                  matlab.ui.control.NumericEditField
        MaxEditField_3Label             matlab.ui.control.Label
        MaxEditField_3                  matlab.ui.control.NumericEditField
        TaperDropDown_3Label            matlab.ui.control.Label
        TaperDropDown_3                 matlab.ui.control.DropDown
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        ObjectStateLabelDropDown_3Label  matlab.ui.control.Label
        ObjectStateLabelDropDown_3      matlab.ui.control.DropDown
        ObjectiveDropDown_3Label        matlab.ui.control.Label
        ObjectiveDropDown_3             matlab.ui.control.DropDown
        Button_3                        matlab.ui.control.Button
        ImageStateLabelDropDown_3Label  matlab.ui.control.Label
        ImageStateLabelDropDown_3       matlab.ui.control.DropDown
        LoadTargetButton_3              matlab.ui.control.Button
        RangeLabel_3                    matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        FullLabel_3                     matlab.ui.control.Label
        CostFunction4Panel              matlab.ui.container.Panel
        Switch_4                        matlab.ui.control.Switch
        MinEditField_4Label             matlab.ui.control.Label
        MinEditField_4                  matlab.ui.control.NumericEditField
        MaxEditField_4Label             matlab.ui.control.Label
        MaxEditField_4                  matlab.ui.control.NumericEditField
        TaperDropDown_4Label            matlab.ui.control.Label
        TaperDropDown_4                 matlab.ui.control.DropDown
        EditField_5Label                matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        ObjectStateLabelDropDown_4Label  matlab.ui.control.Label
        ObjectStateLabelDropDown_4      matlab.ui.control.DropDown
        ObjectiveDropDown_4Label        matlab.ui.control.Label
        ObjectiveDropDown_4             matlab.ui.control.DropDown
        Button_4                        matlab.ui.control.Button
        ImageStateLabelDropDown_4Label  matlab.ui.control.Label
        ImageStateLabelDropDown_4       matlab.ui.control.DropDown
        LoadTargetButton_4              matlab.ui.control.Button
        RangeLabel_4                    matlab.ui.control.Label
        CheckBox_4                      matlab.ui.control.CheckBox
        FullLabel_4                     matlab.ui.control.Label
        ImageControlsPanel              matlab.ui.container.Panel
        LoadImageButton                 matlab.ui.control.Button
        InitializeEmptyImageButton      matlab.ui.control.Button
        VisualizeImageButton            matlab.ui.control.Button
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ObjectControlsPanel             matlab.ui.container.Panel
        SelectObjectButtonGroup         matlab.ui.container.ButtonGroup
        UseCustomObjectButton           matlab.ui.control.RadioButton
        UseObjectLibraryButton          matlab.ui.control.RadioButton
        UsePresetShapesButton           matlab.ui.control.RadioButton
        LoadObjectButton                matlab.ui.control.Button
        LoadObjectLibraryButton         matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        ModulationButtonGroup           matlab.ui.container.ButtonGroup
        RandomRotationsCheckBox         matlab.ui.control.CheckBox
        RandomResizingCheckBox          matlab.ui.control.CheckBox
        RandomStrechingCheckBox         matlab.ui.control.CheckBox
        DropDown_2                      matlab.ui.control.DropDown
        VisualizeObjectButton           matlab.ui.control.Button
        DiagnosticsPanel                matlab.ui.container.Panel
        ConditioningLamp                matlab.ui.control.Lamp
        ConditioningofCostGaugeLabel    matlab.ui.control.Label
        ConditioningofCostGauge         matlab.ui.control.SemicircularGauge
        CapacityLamp                    matlab.ui.control.Lamp
        NewObjectCapacityGaugeLabel     matlab.ui.control.Label
        NewObjectCapacityGauge          matlab.ui.control.SemicircularGauge
        UIAxes                          matlab.ui.control.UIAxes
        GeneratorControlsPanel          matlab.ui.container.Panel
        CostControlsPanel               matlab.ui.container.Panel
        SaveCostPresetButton            matlab.ui.control.Button
        LoadCostPresetButton            matlab.ui.control.Button
        CostAccumulationDropDownLabel   matlab.ui.control.Label
        CostAccumulationDropDown        matlab.ui.control.DropDown
        PixelSwarmRefinementPanel       matlab.ui.container.Panel
        HeuristicDropDownLabel          matlab.ui.control.Label
        HeuristicDropDown               matlab.ui.control.DropDown
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        PixelSwarmPlay                  matlab.ui.control.Button
        CleaningPanel                   matlab.ui.container.Panel
        SmoothButton                    matlab.ui.control.Button
        CloseGapsButton                 matlab.ui.control.Button
        RemoveIslandsButton             matlab.ui.control.Button
        ScaleEditFieldLabel             matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        pixelsLabel                     matlab.ui.control.Label
        ObjectPlacementPanel            matlab.ui.container.Panel
        PlaceObjectPlay                 matlab.ui.control.Button
        HeuristicDropDown_2Label        matlab.ui.control.Label
        HeuristicDropDown_2             matlab.ui.control.DropDown
        IterationsEditField_2Label      matlab.ui.control.Label
        IterationsEditField_2           matlab.ui.control.NumericEditField
        SaveNewImageButton              matlab.ui.control.Button
        VisualizationSettingsButtonGroup  matlab.ui.container.ButtonGroup
        AfterEachObjectSlowButton       matlab.ui.control.RadioButton
        AfterEachStageFastButton        matlab.ui.control.RadioButton
        UpdateCostButton                matlab.ui.control.Button
        CostFunction5Panel              matlab.ui.container.Panel
        Switch_5                        matlab.ui.control.Switch
        MinEditField_5Label             matlab.ui.control.Label
        MinEditField_5                  matlab.ui.control.NumericEditField
        MaxEditField_5Label             matlab.ui.control.Label
        MaxEditField_5                  matlab.ui.control.NumericEditField
        TaperDropDown_5Label            matlab.ui.control.Label
        TaperDropDown_5                 matlab.ui.control.DropDown
        EditField_6Label                matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        ObjectStateLabelDropDown_5Label  matlab.ui.control.Label
        ObjectStateLabelDropDown_5      matlab.ui.control.DropDown
        ObjectiveDropDown_5Label        matlab.ui.control.Label
        ObjectiveDropDown_5             matlab.ui.control.DropDown
        Button_5                        matlab.ui.control.Button
        ImageStateLabelDropDown_5Label  matlab.ui.control.Label
        ImageStateLabelDropDown_5       matlab.ui.control.DropDown
        LoadTargetButton_5              matlab.ui.control.Button
        RangeLabel_5                    matlab.ui.control.Label
        CheckBox_5                      matlab.ui.control.CheckBox
        FullLabel_5                     matlab.ui.control.Label
    end


    properties (Access = private)
        
        % Visual Trackers for the Panels
        CostFunction1PanelEnableValues = cell(21,1);
        CostFunction2PanelEnableValues = cell(21,1);
        CostFunction3PanelEnableValues = cell(21,1);
        CostFunction4PanelEnableValues = cell(21,1);
        CostFunction5PanelEnableValues = cell(21,1);
        CFpanel1uilist = [];
        CFpanel2uilist = [];
        CFpanel3uilist = [];
        CFpanel4uilist = [];
        CFpanel5uilist = [];
        
        % Main Data 
        Image = [];
        ImageStates = [];
        ImageStateList = {};
        Object = [];
        ObjectStates =[];
        ObjectStateList = {};
        
        % Constraint Information
        Constraints = cell(5,1);
        
        % 2D Test
        TestImage2D = [];
        TestObject2D = [];
        
        % 3D Test
        TestImage3D = [];
        TestObject3D = [];
        
        % Statistics Target - Only 1 manually for now.
        TargetCF1 = [];  

    end

    methods (Access = private)
    
        function Constraint = Panel2Constraint(app,panelnumber)
            
            % Get Panel Fields
            panelfields = findall(app.(['CostFunction',num2str(panelnumber),'Panel']).Children, '-property', 'value');
            
            % Get Values from each Field
            useflag = strcmp(panelfields(9).Value,'On');
            
            switch panelfields(3).Value
                case 'Overlap'
                    costtype = 'overlap';
                case 'Seperation'
                    costtype = 'seperation';
                case '2pt Statistics'
                    costtype = 'spatialstats';
            end
            
            if ~isnumeric(str2double(panelfields(2).Value)) && ~strcmp(panelfields(2).Value,'Non-zero')
                errordlg('Load Image First!')
                useflag = false;
                imagelabel = 0;
            elseif strcmp(panelfields(2).Value,'Non-zero')
                imagelabel = 'full';
            else
                imagelabel = str2double(panelfields(2).Value);
            end
            
            if ~isnumeric(str2double(panelfields(4).Value)) && ~strcmp(panelfields(4).Value,'Non-zero')
                errordlg('Load Object First!');
                useflag = false;
                objectlabel = 0;
            elseif strcmp(panelfields(4).Value,'Non-zero')
                objectlabel = 'full';
            else
                objectlabel = str2double(panelfields(4).Value);
            end
            
            if panelfields(1).Value
                range = 'full';
            else
                range = [panelfields(8).Value panelfields(7).Value];
            end
            
            switch panelfields(6).Value
                case 'Gaussian'
                    taper = 'gaussian';
                    relaxation = panelfields(5).Value;
                case 'Absolute'
                    taper = 'absolute';
                    relaxation = 0;
            end
            
            % Build Constraint
            Constraint = ObjectPlacementConstraint(useflag, costtype, imagelabel, objectlabel, range, taper, relaxation);
        end
        
        function Constraint2Panel(app,panelnumber)
            
            % Get Constraint
            Constraint = app.Constraints{panelnumber};
            
            % Get Panel Fields
            panelfieldvalues = findall(app.(['CostFunction',num2str(panelnumber),'Panel']).Children, '-property', 'value');
            panelenables = app.(['CostFunction',num2str(panelnumber),'PanelEnableValues']);
            paneluilist = app.(['CFpanel',num2str(panelnumber),'uilist']);
            
            % Fill Panel
            switch Constraint.CostType
                
                case 'overlap'
                    panelfieldvalues(3).Value = 'Overlap';
                    panelenables([1,2,4]) = {'off'};
                    panelenables([3,12,17:20]) = {'on'};
                    paneluilist(12).Text = '%';
                    paneluilist(7).Icon = 'overlap.png';
                    for i=1:length(app.CFpanel1uilist)
                        paneluilist(i).Enable = panelenables{i};
                    end
                    
                case 'seperation'
                    panelfieldvalues(3).Value = 'Seperation';
                    panelenables([1,2,4]) = {'off'};
                    panelenables([3,12,17:20]) = {'on'};
                    paneluilist(12).Text = 'pixels';
                    paneluilist(7).Icon = 'seperation.png';
                    for i=1:length(app.CFpanel1uilist)
                        paneluilist(i).Enable = panelenables{i};
                    end
                    
                case 'spatialstats'
                    panelfieldvalues(3).Value = '2pt Statistics';                
                    panelenables([1,2,4]) = {'on'};
                    panelfieldvalues(1).Value = 1;
                    panelenables([3,12,17:20]) = {'off'};
                    paneluilist(7).Icon = 'stats.png';
                    for i=1:length(app.CFpanel1uilist)
                        paneluilist(i).Enable = panelenables{i};
                    end
                    
            end
            
            if ismember(string(Constraint.ImageROILabel),panelfieldvalues(2).Items)
                panelfieldvalues(2).Value = char(string(Constraint.ImageROILabel));
            else
                h1 = warndlg('No compatible Image found. Manually modify State Labels when you load the Image!','NoImage-LoadConstraint','modal');
            end
            
            if ismember(string(Constraint.ObjectROILabel),panelfieldvalues(4).Items)
                panelfieldvalues(4).Value = char(string(Constraint.ObjectROILabel));
            else
                warndlg('No compatible Object found. Manually modify State Labels when you load the Object!','NoObject-LoadConstraint','modal');
            end

            if strcmp(Constraint.Range,'full')
                panelfieldvalues(1).Value = true;
            else
                panelfieldvalues(8).Value = Constraint.Range(1);
                panelfieldvalues(7).Value = Constraint.Range(2);
            end
            
            switch Constraint.Taper
                case 'gaussian'
                    panelfieldvalues(6).Value = 'Gaussian';
                    panelfieldvalues(5).Value = Constraint.Relaxation;
                case 'absolute'
                    panelfieldvalues(6).Value = 'Absolute';
                    panelfieldvalues(5).Enable = 'off';
            end
            
            if Constraint.UseFlag
                panelfieldvalues(9).Value = 'On';
            else
                panelfieldvalues(9).Value = 'Off';
                set(paneluilist, 'enable', 'Off')
                paneluilist(21).Enable = 'On';
            end

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screensize = get( groot, 'Screensize' );
            app.MicrostructureGeneratorUIFigure.Position(1) = 25;
            app.MicrostructureGeneratorUIFigure.Position(2) = screensize(4) - app.MicrostructureGeneratorUIFigure.Position(4) - 30;
            
            % Panel 1
            app.CostFunction1PanelEnableValues(:) = {'on'};
            app.CostFunction1PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel1uilist = findall(app.CostFunction1Panel.Children, '-property', 'enable');
            
            % Panel 2
            app.CostFunction2PanelEnableValues(:) = {'on'};
            app.CostFunction2PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel2uilist = findall(app.CostFunction2Panel.Children, '-property', 'enable');
            
            % Panel 3
            app.CostFunction3PanelEnableValues(:) = {'on'};
            app.CostFunction3PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel3uilist = findall(app.CostFunction3Panel.Children, '-property', 'enable');
            
            % Panel 4
            app.CostFunction4PanelEnableValues(:) = {'on'};
            app.CostFunction4PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel4uilist = findall(app.CostFunction4Panel.Children, '-property', 'enable');
            
            % Panel 5
            app.CostFunction5PanelEnableValues(:) = {'on'};
            app.CostFunction5PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel5uilist = findall(app.CostFunction5Panel.Children, '-property', 'enable');
            
            % Test 2D
            S = load('Image.mat');
            Snames = fieldnames(S);
            app.TestImage2D = S.(Snames{1});
            
            S = load('Object.mat');
            Snames = fieldnames(S);
            app.TestObject2D = S.(Snames{1});
            
            % Test 3D
            S = load('Sample3DImage.mat');
            Snames = fieldnames(S);
            app.TestImage3D = S.(Snames{1});
            
            S = load('Sample3DObject.mat');
            Snames = fieldnames(S);
            app.TestObject3D = S.(Snames{1});
            
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.CostFunction1PanelEnableValues([3,12,17:20]) = {'off'};
                for i=1:length(app.CFpanel1uilist)
                    app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                end
            elseif value == 0
                app.CostFunction1PanelEnableValues([3,12,17:20]) = {'on'};
                for i=1:length(app.CFpanel1uilist)
                    app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                end
            end             
        end

        % Button pushed function: InitializeEmptyImageButton
        function InitializeEmptyImageButtonPushed(app, event)
            app.Image = zeros(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value);
            statelist = cellstr(string(unique([0 1])));
            statelist{end+1} = 'Non-zero';
            app.ImageStateList = statelist;
            
            diff = setdiff(app.ObjectStateList,app.ImageStateList);
            if ~isempty(diff)
                for ii = 1:numel(diff)
                    app.ImageStateList{end+1} = diff{ii};
                end
            end
            
            app.ImageStateLabelDropDown.Items = app.ImageStateList;           
            app.ImageStateLabelDropDown_2.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_3.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_4.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_5.Items = app.ImageStateList;
        end

        % Button pushed function: LoadCostPresetButton
        function LoadCostPresetButtonPushed(app, event)
            S = uiimport('-file');
            Snames = fieldnames(S);
            
            if Snames{1} ~= 'SavedConstraints'
                errordlg('File does not contain constraints!'); 
            end
            
            SavedConstraints = S.(Snames{1});
              
            for ii = 1:length(SavedConstraints)
                if ~isa(SavedConstraints{ii},'ObjectPlacementConstraint')
                    error('Constraints can only contain ObjectPlacementConstraint objects in a cell!');
                end
                app.Constraints{ii} = SavedConstraints{ii};
                Constraint2Panel(app,ii);
            end  
      
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            S = uiimport('-file');
            Snames = fieldnames(S);
            app.Image = S.(Snames{1});
            app.ImageStates = unique(app.Image);
            statelist = cellstr(string(unique(app.Image)));
            statelist{end+1} = 'Non-zero';
            app.ImageStateList = statelist;
            
            diff = setdiff(app.ObjectStateList,app.ImageStateList);
            if ~isempty(diff)
                for ii = 1:numel(diff)
                    app.ImageStateList{end+1} = diff{ii};
                end
            end
            
            app.ImageStateLabelDropDown.Items = app.ImageStateList;           
            app.ImageStateLabelDropDown_2.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_3.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_4.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_5.Items = app.ImageStateList;
        end

        % Callback function
        function LoadObjectButtonPushed(app, event)
            S = uiimport('-file');
            Snames = fieldnames(S);
            app.Object = S.(Snames{1});
            app.ObjectStates = unique(app.Object);
            statelist = cellstr(string(unique(app.Object)));
            statelist{end+1} = 'Non-zero';
            app.ObjectStateList = statelist;
            app.ObjectStateLabelDropDown.Items = statelist;
            app.ObjectStateLabelDropDown_2.Items = statelist;
            app.ObjectStateLabelDropDown_3.Items = statelist;
            app.ObjectStateLabelDropDown_4.Items = statelist;
            app.ObjectStateLabelDropDown_5.Items = statelist;
            
            diff = setdiff(app.ObjectStateList,app.ImageStateList);
            if ~isempty(diff)
                for ii = 1:numel(diff)
                    app.ImageStateList{end+1} = diff{ii};
                end
            end
            
            app.ImageStateLabelDropDown.Items = app.ImageStateList;           
            app.ImageStateLabelDropDown_2.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_3.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_4.Items = app.ImageStateList;
            app.ImageStateLabelDropDown_5.Items = app.ImageStateList;
        end

        % Button pushed function: LoadTargetButton
        function LoadTargetButtonPushed(app, event)
            S = uiimport('-file');
            Snames = fieldnames(S);
            app.TargetCF1 = S.(Snames{1});
        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveDropDownValueChanged(app, event)
            value = app.ObjectiveDropDown.Value;
            switch value
                case 'Overlap'
                    
                    app.CostFunction1PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction1PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel1uilist(12).Text = '%';
                    app.CFpanel1uilist(7).Icon = 'overlap.png';
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction1PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction1PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel1uilist(12).Text = 'pixels';
                    app.CFpanel1uilist(7).Icon = 'seperation.png';
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
                case '2pt Statistics'
                    app.CostFunction1PanelEnableValues([1,2,4]) = {'on'};
                    app.CFpanel1uilist(2).Value = 1;
                    app.CostFunction1PanelEnableValues([3,12,17:20]) = {'off'};
                    app.CFpanel1uilist(7).Icon = 'stats.png';
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
            end
                    
        end

        % Value changed function: ObjectiveDropDown_2
        function ObjectiveDropDown_2ValueChanged(app, event)
            value = app.ObjectiveDropDown_2.Value;
            switch value
                case 'Overlap'
                    app.CostFunction2PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction2PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel2uilist(12).Text = '%';
                    app.CFpanel2uilist(7).Icon = 'overlap.png';
                    for i=1:length(app.CFpanel2uilist)
                        app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction2PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction2PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel2uilist(12).Text = 'pixels';
                    app.CFpanel2uilist(7).Icon = 'seperation.png';
                    for i=1:length(app.CFpanel2uilist)
                        app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                    end
            %                 case '2pt Statistics'
            %                     app.CostFunction2PanelEnableValues([1,2,4]) = {'on'};
            %                     app.CheckBox_2.Value = 1;
            %                     app.CostFunction2PanelEnableValues([3,12,17:20]) = {'off'};
            %                     for i=1:length(app.CFpanel2uilist)
            %                         app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
            %                     end
            end
            
        end

        % Value changed function: ObjectiveDropDown_3
        function ObjectiveDropDown_3ValueChanged(app, event)
            value = app.ObjectiveDropDown_3.Value;
            switch value
                case 'Overlap'
                    app.CostFunction3PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction3PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel3uilist(12).Text = '%';
                    app.CFpanel3uilist(7).Icon = 'overlap.png';
                    for i=1:length(app.CFpanel3uilist)
                        app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction3PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction3PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel3uilist(12).Text = 'pixels';
                    app.CFpanel3uilist(7).Icon = 'seperation.png';
                    for i=1:length(app.CFpanel3uilist)
                        app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                    end
            %                 case '2pt Statistics'
            %                     app.CostFunction3PanelEnableValues([1,2,4]) = {'on'};
            %                     app.CheckBox_3.Value = 1;
            %                     app.CostFunction3PanelEnableValues([3,12,17:20]) = {'off'};
            %                     for i=1:length(app.CFpanel3uilist)
            %                         app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
            %                     end
            end
            
        end

        % Value changed function: ObjectiveDropDown_4
        function ObjectiveDropDown_4ValueChanged(app, event)
            value = app.ObjectiveDropDown_4.Value;
            switch value
                case 'Overlap'
                    app.CostFunction4PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction4PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel4uilist(12).Text = '%';
                    app.CFpanel4uilist(7).Icon = 'overlap.png';
                    for i=1:length(app.CFpanel4uilist)
                        app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction4PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction4PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel4uilist(12).Text = 'pixels';
                    app.CFpanel4uilist(7).Icon = 'seperation.png';
                    for i=1:length(app.CFpanel4uilist)
                        app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                    end
            %                 case '2pt Statistics'
            %                     app.CostFunction4PanelEnableValues([1,2,4]) = {'on'};
            %                     app.CheckBox_4.Value = 1;
            %                     app.CostFunction4PanelEnableValues([3,12,17:20]) = {'off'};
            %                     for i=1:length(app.CFpanel4uilist)
            %                         app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
            %                     end
            end
            
        end

        % Value changed function: ObjectiveDropDown_5
        function ObjectiveDropDown_5ValueChanged(app, event)
            value = app.ObjectiveDropDown_5.Value;
            switch value
                case 'Overlap'
                    app.CostFunction5PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction5PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel5uilist(12).Text = '%';
                    app.CFpanel5uilist(7).Icon = 'overlap.png';
                    for i=1:length(app.CFpanel5uilist)
                        app.CFpanel5uilist(i).Enable = app.CostFunction5PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction5PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction5PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel5uilist(12).Text = 'pixels';
                    app.CFpanel5uilist(7).Icon = 'seperation.png';
                    for i=1:length(app.CFpanel5uilist)
                        app.CFpanel5uilist(i).Enable = app.CostFunction5PanelEnableValues{i};
                    end
            %                 case '2pt Statistics'
            %                     app.CostFunction2PanelEnableValues([1,2,4]) = {'on'};
            %                     app.CheckBox_2.Value = 1;
            %                     app.CostFunction2PanelEnableValues([3,12,17:20]) = {'off'};
            %                     for i=1:length(app.CFpanel2uilist)
            %                         app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
            %                     end
            end
        end

        % Button pushed function: PixelSwarmPlay
        function PixelSwarmPlayButtonPushed(app, event)
            figure; view(3);
            
            button = questdlg('Pixel swarm currently treats the microstructure as binary. Any multiphase/ROI labels will be lost.Do you still want to continue?','Warning!','Yes','No','Yes');
            
            if ~isempty(button)


            %                 switch button
            %                 
            %                     costmap = GetPixelSwarmStatsCostFunction(Image,targetstats,radiusrange,taper,relaxation);
            %                 
            %                 for ii = 1:app.IterationsEditField.Value
            %                     
            %                     
            %                 end 
            %                 
            end
            
        end

        % Button pushed function: PlaceObjectPlay
        function PlaceObjectPlayPushed(app, event)
            figure; view(3);
            for ii = 1:app.IterationsEditField_2.Value
                if app.RandomRotationsCheckBox.Value == true
                    if ndims(app.Image) == 3
                        object = IntrinsicRotate3D(app.Object, 'xyz', [randi(360) randi(180) randi(360)], 'bilinear');
                    else
                        object = imrotate(app.Object,randi(360),'bilinear');
                    end
                else
                    object = app.Object;
                end
                
                switch app.CostAccumulationDropDown.Value
                    case 'Additive'
                        aggregator = 'plus';
                    case 'Multiplicative'
                        aggregator = 'times';
                end
                
                
                costmap = CompileCostFunctions(app.Image ,object ,app.TargetCF1, app.Constraints, aggregator);
                
                Conditioning = mean(costmap(costmap >= prctile(costmap(:),90)));
                app.ConditioningofCostGauge.Value = Conditioning*100;
                if Conditioning <= 0.5
                    app.ConditioningLamp.Color = [1.00,0.00,0.00];
                elseif Conditioning > 0.5 && Conditioning < 0.8
                    app.ConditioningLamp.Color = [0.93,0.69,0.13];
                else
                    app.ConditioningLamp.Color = [0.00,1.00,0.00];
                end
                
                Capacity = nnz(app.Image==0)/numel(app.Image);
                app.NewObjectCapacityGauge.Value = Capacity*100;
                if Capacity <= 0.5
                    app.CapacityLamp.Color = [1.00,0.00,0.00];
                elseif Capacity > 0.5 && Capacity < 0.7
                    app.CapacityLamp.Color = [0.93,0.69,0.13];
                else
                    app.CapacityLamp.Color = [0.00,1.00,0.00];
                end
                
                switch app.HeuristicDropDown_2.Value
                    case 'Thresholded Random'
                        heuristic = 'thresholdedrandom';
                    case 'Weighted Random'
                        heuristic = 'weightedrandom';
                    case 'Percentile Random'
                        heuristic = 'percentilerandom';
                end         
                
                try 
                    app.Image = PlaceObject(app.Image, object, costmap, heuristic);
                catch
                    warndlg('No more objects can be placed under current cost functions and heuristics!');
                    break
                end
                
                if app.AfterEachObjectSlowButton.Value == 1
                    
                    if ndims(app.Image) == 3
                        vol3d('cdata',smooth3(app.Image)); axis tight; colormap cool; axis image;
                    else
                        imagesc(app.Image); axis tight; colormap cool; axis image;
                    end
                    drawnow;
                    
                end                
            end
            
            if app.AfterEachStageFastButton.Value == 1                  
                if ndims(app.Image) == 3
                    vol3d('cdata',smooth3(app.Image)); axis tight; colormap cool; axis image;
                else
                    imagesc(app.Image); axis tight; colormap cool; axis image;
                end
            end
               
        end

        % Button pushed function: SaveCostPresetButton
        function SaveCostPresetButtonPushed(app, event)
            SavedConstraints = app.Constraints;
            uisave('SavedConstraints','MyConstraints.mat');
        end

        % Button pushed function: SaveNewImageButton
        function SaveNewImageButtonPushed(app, event)
            GeneratedImage = app.Image; %#ok<NASGU>
            uisave('GeneratedImage','MyNewImage.mat');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                set(app.CFpanel1uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel1uilist)
                    app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                end
            end
            app.Switch.Enable = 'on';
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value,'Off')
                set(app.CFpanel2uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel2uilist)
                    app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                end
            end
            app.Switch_2.Enable = 'on';
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if strcmp(value,'Off')
                set(app.CFpanel3uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel3uilist)
                    app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                end
            end
            app.Switch_3.Enable = 'on';
            
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if strcmp(value,'Off')
                set(app.CFpanel4uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel4uilist)
                    app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                end
            end
            app.Switch_4.Enable = 'on';
            
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            if strcmp(value,'Off')
                set(app.CFpanel5uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel5uilist)
                    app.CFpanel5uilist(i).Enable = app.CostFunction5PanelEnableValues{i};
                end
            end
            app.Switch_5.Enable = 'on';
        end

        % Value changed function: TaperDropDown
        function TaperDropDownValueChanged(app, event)
            value = app.TaperDropDown.Value;
            switch value
                case 'Gaussian'
                    app.CostFunction1PanelEnableValues([14,13]) = {'on'};
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
                case 'Absolute'
                    app.CostFunction1PanelEnableValues([14,13]) = {'off'};
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
            end
        end

        % Value changed function: TaperDropDown_2
        function TaperDropDown_2ValueChanged(app, event)
            value = app.TaperDropDown_2.Value;
            switch value
                case 'Gaussian'
                    app.CostFunction2PanelEnableValues([14,13]) = {'on'};
                    for i=1:length(app.CFpanel2uilist)
                        app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                    end
                case 'Absolute'
                    app.CostFunction2PanelEnableValues([14,13]) = {'off'};
                    for i=1:length(app.CFpanel2uilist)
                        app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                    end
            end
            
        end

        % Value changed function: TaperDropDown_3
        function TaperDropDown_3ValueChanged(app, event)
            value = app.TaperDropDown_3.Value;
            switch value
                case 'Gaussian'
                    app.CostFunction3PanelEnableValues([14,13]) = {'on'};
                    for i=1:length(app.CFpanel3uilist)
                        app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                    end
                case 'Absolute'
                    app.CostFunction3PanelEnableValues([14,13]) = {'off'};
                    for i=1:length(app.CFpanel3uilist)
                        app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                    end
            end
        end

        % Value changed function: TaperDropDown_4
        function TaperDropDown_4ValueChanged(app, event)
            value = app.TaperDropDown_4.Value;
            switch value
                case 'Gaussian'
                    app.CostFunction4PanelEnableValues([14,13]) = {'on'};
                    for i=1:length(app.CFpanel4uilist)
                        app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                    end
                case 'Absolute'
                    app.CostFunction4PanelEnableValues([14,13]) = {'off'};
                    for i=1:length(app.CFpanel4uilist)
                        app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                    end
            end
        end

        % Value changed function: TaperDropDown_5
        function TaperDropDown_5ValueChanged(app, event)
            value = app.TaperDropDown_5.Value;
            switch value
                case 'Gaussian'
                    app.CostFunction5PanelEnableValues([14,13]) = {'on'};
                    for i=1:length(app.CFpanel5uilist)
                        app.CFpanel5uilist(i).Enable = app.CostFunction5PanelEnableValues{i};
                    end
                case 'Absolute'
                    app.CostFunction5PanelEnableValues([14,13]) = {'off'};
                    for i=1:length(app.CFpanel5uilist)
                        app.CFpanel5uilist(i).Enable = app.CostFunction5PanelEnableValues{i};
                    end
            end
        end

        % Button pushed function: UpdateCostButton
        function UpdateCostButtonPushed(app, event)
            for ii = 1:5
                app.Constraints{ii} = Panel2Constraint(app,ii);
            end
            
            if ismatrix(app.Image)
               costmap = CompileCostFunctions(app.TestImage2D ,app.TestObject2D ,app.TargetCF1, app.Constraints,'times');
            elseif ndims(app.Image) == 3
               costmap = CompileCostFunctions(app.TestImage3D ,app.TestObject3D ,app.TargetCF1, app.Constraints,'times');
            end
            
            Conditioning = mean(costmap(costmap >= prctile(costmap(:),90)));
            app.ConditioningofCostGauge.Value = Conditioning*100;
            if Conditioning <= 0.5
                app.ConditioningLamp.Color = [1.00,0.00,0.00];
            elseif Conditioning > 0.5 && Conditioning < 0.8
                app.ConditioningLamp.Color = [0.93,0.69,0.13];
            else
                app.ConditioningLamp.Color = [0.00,1.00,0.00];
            end
            
            Capacity = nnz(app.Image==0)/numel(app.Image);
            app.NewObjectCapacityGauge.Value = Capacity*100;
            if Capacity <= 0.5
                app.CapacityLamp.Color = [1.00,0.00,0.00];
            elseif Capacity > 0.5 && Capacity < 0.7
                app.CapacityLamp.Color = [0.93,0.69,0.13];
            else
                app.CapacityLamp.Color = [0.00,1.00,0.00];
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrostructureGeneratorUIFigure = uifigure;
            app.MicrostructureGeneratorUIFigure.Name = 'Microstructure Generator';
            app.CostFunction1Panel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.CostFunction1Panel.Title = 'Cost Function #1';
            app.Switch = uiswitch(app.CostFunction1Panel, 'slider');
            app.Switch.Items = {'On', 'Off'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.CostFunction1Panel);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CostFunction1Panel, 'numeric');
            app.MinEditField.Enable = 'off';
            app.MaxEditFieldLabel = uilabel(app.CostFunction1Panel);
            app.MaxEditFieldLabel.Enable = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CostFunction1Panel, 'numeric');
            app.MaxEditField.Enable = 'off';
            app.TaperDropDownLabel = uilabel(app.CostFunction1Panel);
            app.TaperDropDownLabel.Enable = 'off';
            app.TaperDropDownLabel.Text = 'Taper';
            app.TaperDropDown = uidropdown(app.CostFunction1Panel);
            app.TaperDropDown.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown.ValueChangedFcn = createCallbackFcn(app, @TaperDropDownValueChanged, true);
            app.TaperDropDown.Enable = 'off';
            app.TaperDropDown.Value = 'Gaussian';
            app.Label = uilabel(app.CostFunction1Panel);
            app.Label.Enable = 'off';
            app.Label.Text = 'σ';
            app.EditField_2 = uieditfield(app.CostFunction1Panel, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 1;
            app.Label_2 = uilabel(app.CostFunction1Panel);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '%';
            app.ObjectStateLabelDropDownLabel = uilabel(app.CostFunction1Panel);
            app.ObjectStateLabelDropDownLabel.Enable = 'off';
            app.ObjectStateLabelDropDownLabel.Text = 'Object State Label';
            app.ObjectStateLabelDropDown = uidropdown(app.CostFunction1Panel);
            app.ObjectStateLabelDropDown.Enable = 'off';
            app.ObjectiveDropDownLabel = uilabel(app.CostFunction1Panel);
            app.ObjectiveDropDownLabel.Enable = 'off';
            app.ObjectiveDropDownLabel.Text = 'Objective:';
            app.ObjectiveDropDown = uidropdown(app.CostFunction1Panel);
            app.ObjectiveDropDown.Items = {'Overlap', 'Seperation', '2pt Statistics'};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDownValueChanged, true);
            app.ObjectiveDropDown.Enable = 'off';
            app.ObjectiveDropDown.Value = 'Overlap';
            app.Button = uibutton(app.CostFunction1Panel, 'push');
            app.Button.Icon = 'overlap.png';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.ImageStateLabelDropDownLabel = uilabel(app.CostFunction1Panel);
            app.ImageStateLabelDropDownLabel.Enable = 'off';
            app.ImageStateLabelDropDownLabel.Text = 'Image State Label';
            app.ImageStateLabelDropDown = uidropdown(app.CostFunction1Panel);
            app.ImageStateLabelDropDown.Enable = 'off';
            app.LoadTargetButton = uibutton(app.CostFunction1Panel, 'push');
            app.LoadTargetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTargetButtonPushed, true);
            app.LoadTargetButton.Enable = 'off';
            app.LoadTargetButton.Text = 'Load Target';
            app.RangeLabel = uilabel(app.CostFunction1Panel);
            app.RangeLabel.Enable = 'off';
            app.RangeLabel.Text = 'Range:';
            app.CheckBox = uicheckbox(app.CostFunction1Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.FullLabel = uilabel(app.CostFunction1Panel);
            app.FullLabel.Enable = 'off';
            app.FullLabel.Text = 'Full';
            app.CostFunction2Panel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.CostFunction2Panel.Title = 'Cost Function #2';
            app.Switch_2 = uiswitch(app.CostFunction2Panel, 'slider');
            app.Switch_2.Items = {'On', 'Off'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.MinEditField_2Label = uilabel(app.CostFunction2Panel);
            app.MinEditField_2Label.Enable = 'off';
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.CostFunction2Panel, 'numeric');
            app.MinEditField_2.Enable = 'off';
            app.MaxEditField_2Label = uilabel(app.CostFunction2Panel);
            app.MaxEditField_2Label.Enable = 'off';
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.CostFunction2Panel, 'numeric');
            app.MaxEditField_2.Enable = 'off';
            app.TaperDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.TaperDropDown_2Label.Enable = 'off';
            app.TaperDropDown_2Label.Text = 'Taper';
            app.TaperDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.TaperDropDown_2.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TaperDropDown_2ValueChanged, true);
            app.TaperDropDown_2.Enable = 'off';
            app.TaperDropDown_2.Value = 'Gaussian';
            app.EditField_3Label = uilabel(app.CostFunction2Panel);
            app.EditField_3Label.Enable = 'off';
            app.EditField_3Label.Text = 'σ';
            app.EditField_3 = uieditfield(app.CostFunction2Panel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = 1;
            app.Label_3 = uilabel(app.CostFunction2Panel);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '%';
            app.ObjectStateLabelDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.ObjectStateLabelDropDown_2Label.Enable = 'off';
            app.ObjectStateLabelDropDown_2Label.Text = 'Object State Label';
            app.ObjectStateLabelDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.ObjectStateLabelDropDown_2.Enable = 'off';
            app.ObjectiveDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.ObjectiveDropDown_2Label.Enable = 'off';
            app.ObjectiveDropDown_2Label.Text = 'Objective:';
            app.ObjectiveDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.ObjectiveDropDown_2.Items = {'Overlap', 'Seperation'};
            app.ObjectiveDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDown_2ValueChanged, true);
            app.ObjectiveDropDown_2.Enable = 'off';
            app.ObjectiveDropDown_2.Value = 'Overlap';
            app.Button_2 = uibutton(app.CostFunction2Panel, 'push');
            app.Button_2.Icon = 'overlap.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.ImageStateLabelDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.ImageStateLabelDropDown_2Label.Enable = 'off';
            app.ImageStateLabelDropDown_2Label.Text = 'Image State Label';
            app.ImageStateLabelDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.ImageStateLabelDropDown_2.Enable = 'off';
            app.LoadTargetButton_2 = uibutton(app.CostFunction2Panel, 'push');
            app.LoadTargetButton_2.Enable = 'off';
            app.LoadTargetButton_2.Text = 'Load Target';
            app.RangeLabel_2 = uilabel(app.CostFunction2Panel);
            app.RangeLabel_2.Enable = 'off';
            app.RangeLabel_2.Text = 'Range:';
            app.CheckBox_2 = uicheckbox(app.CostFunction2Panel);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.FullLabel_2 = uilabel(app.CostFunction2Panel);
            app.FullLabel_2.Enable = 'off';
            app.FullLabel_2.Text = 'Full';
            app.CostFunction3Panel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.CostFunction3Panel.Title = 'Cost Function #3';
            app.Switch_3 = uiswitch(app.CostFunction3Panel, 'slider');
            app.Switch_3.Items = {'On', 'Off'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.MinEditField_3Label = uilabel(app.CostFunction3Panel);
            app.MinEditField_3Label.Enable = 'off';
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_3 = uieditfield(app.CostFunction3Panel, 'numeric');
            app.MinEditField_3.Enable = 'off';
            app.MaxEditField_3Label = uilabel(app.CostFunction3Panel);
            app.MaxEditField_3Label.Enable = 'off';
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.CostFunction3Panel, 'numeric');
            app.MaxEditField_3.Enable = 'off';
            app.TaperDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.TaperDropDown_3Label.Enable = 'off';
            app.TaperDropDown_3Label.Text = 'Taper';
            app.TaperDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.TaperDropDown_3.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown_3.ValueChangedFcn = createCallbackFcn(app, @TaperDropDown_3ValueChanged, true);
            app.TaperDropDown_3.Enable = 'off';
            app.TaperDropDown_3.Value = 'Gaussian';
            app.EditField_4Label = uilabel(app.CostFunction3Panel);
            app.EditField_4Label.Enable = 'off';
            app.EditField_4Label.Text = 'σ';
            app.EditField_4 = uieditfield(app.CostFunction3Panel, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.Enable = 'off';
            app.EditField_4.Value = 1;
            app.Label_4 = uilabel(app.CostFunction3Panel);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '%';
            app.ObjectStateLabelDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.ObjectStateLabelDropDown_3Label.Enable = 'off';
            app.ObjectStateLabelDropDown_3Label.Text = 'Object State Label';
            app.ObjectStateLabelDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.ObjectStateLabelDropDown_3.Enable = 'off';
            app.ObjectiveDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.ObjectiveDropDown_3Label.Enable = 'off';
            app.ObjectiveDropDown_3Label.Text = 'Objective:';
            app.ObjectiveDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.ObjectiveDropDown_3.Items = {'Overlap', 'Seperation'};
            app.ObjectiveDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDown_3ValueChanged, true);
            app.ObjectiveDropDown_3.Enable = 'off';
            app.ObjectiveDropDown_3.Value = 'Overlap';
            app.Button_3 = uibutton(app.CostFunction3Panel, 'push');
            app.Button_3.Icon = 'overlap.png';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.ImageStateLabelDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.ImageStateLabelDropDown_3Label.Enable = 'off';
            app.ImageStateLabelDropDown_3Label.Text = 'Image State Label';
            app.ImageStateLabelDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.ImageStateLabelDropDown_3.Enable = 'off';
            app.LoadTargetButton_3 = uibutton(app.CostFunction3Panel, 'push');
            app.LoadTargetButton_3.Enable = 'off';
            app.LoadTargetButton_3.Text = 'Load Target';
            app.RangeLabel_3 = uilabel(app.CostFunction3Panel);
            app.RangeLabel_3.Enable = 'off';
            app.RangeLabel_3.Text = 'Range:';
            app.CheckBox_3 = uicheckbox(app.CostFunction3Panel);
            app.CheckBox_3.Enable = 'off';
            app.CheckBox_3.Text = '';
            app.FullLabel_3 = uilabel(app.CostFunction3Panel);
            app.FullLabel_3.Enable = 'off';
            app.FullLabel_3.Text = 'Full';
            app.CostFunction4Panel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.CostFunction4Panel.Title = 'Cost Function #4';
            app.Switch_4 = uiswitch(app.CostFunction4Panel, 'slider');
            app.Switch_4.Items = {'On', 'Off'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.MinEditField_4Label = uilabel(app.CostFunction4Panel);
            app.MinEditField_4Label.Enable = 'off';
            app.MinEditField_4Label.Text = 'Min';
            app.MinEditField_4 = uieditfield(app.CostFunction4Panel, 'numeric');
            app.MinEditField_4.Enable = 'off';
            app.MaxEditField_4Label = uilabel(app.CostFunction4Panel);
            app.MaxEditField_4Label.Enable = 'off';
            app.MaxEditField_4Label.Text = 'Max';
            app.MaxEditField_4 = uieditfield(app.CostFunction4Panel, 'numeric');
            app.MaxEditField_4.Enable = 'off';
            app.TaperDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.TaperDropDown_4Label.Enable = 'off';
            app.TaperDropDown_4Label.Text = 'Taper';
            app.TaperDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.TaperDropDown_4.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown_4.ValueChangedFcn = createCallbackFcn(app, @TaperDropDown_4ValueChanged, true);
            app.TaperDropDown_4.Enable = 'off';
            app.TaperDropDown_4.Value = 'Gaussian';
            app.EditField_5Label = uilabel(app.CostFunction4Panel);
            app.EditField_5Label.Enable = 'off';
            app.EditField_5Label.Text = 'σ';
            app.EditField_5 = uieditfield(app.CostFunction4Panel, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.Enable = 'off';
            app.EditField_5.Value = 1;
            app.Label_5 = uilabel(app.CostFunction4Panel);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = '%';
            app.ObjectStateLabelDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.ObjectStateLabelDropDown_4Label.Enable = 'off';
            app.ObjectStateLabelDropDown_4Label.Text = 'Object State Label';
            app.ObjectStateLabelDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.ObjectStateLabelDropDown_4.Enable = 'off';
            app.ObjectiveDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.ObjectiveDropDown_4Label.Enable = 'off';
            app.ObjectiveDropDown_4Label.Text = 'Objective:';
            app.ObjectiveDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.ObjectiveDropDown_4.Items = {'Overlap', 'Seperation'};
            app.ObjectiveDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDown_4ValueChanged, true);
            app.ObjectiveDropDown_4.Enable = 'off';
            app.ObjectiveDropDown_4.Value = 'Overlap';
            app.Button_4 = uibutton(app.CostFunction4Panel, 'push');
            app.Button_4.Icon = 'overlap.png';
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '';
            app.ImageStateLabelDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.ImageStateLabelDropDown_4Label.Enable = 'off';
            app.ImageStateLabelDropDown_4Label.Text = 'Image State Label';
            app.ImageStateLabelDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.ImageStateLabelDropDown_4.Enable = 'off';
            app.LoadTargetButton_4 = uibutton(app.CostFunction4Panel, 'push');
            app.LoadTargetButton_4.Enable = 'off';
            app.LoadTargetButton_4.Text = 'Load Target';
            app.RangeLabel_4 = uilabel(app.CostFunction4Panel);
            app.RangeLabel_4.Enable = 'off';
            app.RangeLabel_4.Text = 'Range:';
            app.CheckBox_4 = uicheckbox(app.CostFunction4Panel);
            app.CheckBox_4.Enable = 'off';
            app.CheckBox_4.Text = '';
            app.FullLabel_4 = uilabel(app.CostFunction4Panel);
            app.FullLabel_4.Enable = 'off';
            app.FullLabel_4.Text = 'Full';
            app.ImageControlsPanel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.ImageControlsPanel.Title = 'Image Controls';
            app.LoadImageButton = uibutton(app.ImageControlsPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.InitializeEmptyImageButton = uibutton(app.ImageControlsPanel, 'push');
            app.InitializeEmptyImageButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeEmptyImageButtonPushed, true);
            app.InitializeEmptyImageButton.Text = 'Initialize Empty Image';
            app.VisualizeImageButton = uibutton(app.ImageControlsPanel, 'push');
            app.VisualizeImageButton.Enable = 'off';
            app.VisualizeImageButton.Text = 'Visualize Image';
            app.XEditFieldLabel = uilabel(app.ImageControlsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ImageControlsPanel, 'numeric');
            app.XEditField.Value = 101;
            app.YEditFieldLabel = uilabel(app.ImageControlsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ImageControlsPanel, 'numeric');
            app.YEditField.Value = 101;
            app.ZEditFieldLabel = uilabel(app.ImageControlsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ImageControlsPanel, 'numeric');
            app.ZEditField.Value = 101;
            app.ObjectControlsPanel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.ObjectControlsPanel.Title = 'Object Controls';
            app.SelectObjectButtonGroup = uibuttongroup(app.ObjectControlsPanel);
            app.SelectObjectButtonGroup.Title = 'Select Object';
            app.UseCustomObjectButton = uiradiobutton(app.SelectObjectButtonGroup);
            app.UseCustomObjectButton.Text = 'Use Custom Object';
            app.UseCustomObjectButton.Value = true;
            app.UseObjectLibraryButton = uiradiobutton(app.SelectObjectButtonGroup);
            app.UseObjectLibraryButton.Enable = 'off';
            app.UseObjectLibraryButton.Text = 'Use Object Library';
            app.UsePresetShapesButton = uiradiobutton(app.SelectObjectButtonGroup);
            app.UsePresetShapesButton.Enable = 'off';
            app.UsePresetShapesButton.Text = 'Use Preset Shapes';
            app.LoadObjectButton = uibutton(app.SelectObjectButtonGroup, 'push');
            app.LoadObjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadObjectButtonPushed, true);
            app.LoadObjectButton.Text = 'Load Object';
            app.LoadObjectLibraryButton = uibutton(app.SelectObjectButtonGroup, 'push');
            app.LoadObjectLibraryButton.Enable = 'off';
            app.LoadObjectLibraryButton.Text = 'Load Object Library';
            app.DropDown = uidropdown(app.SelectObjectButtonGroup);
            app.DropDown.Items = {'Rectangles', 'Ellipses'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Rectangles';
            app.ModulationButtonGroup = uibuttongroup(app.ObjectControlsPanel);
            app.ModulationButtonGroup.Title = 'Modulation';
            app.RandomRotationsCheckBox = uicheckbox(app.ModulationButtonGroup);
            app.RandomRotationsCheckBox.Text = ' Random Rotations';
            app.RandomRotationsCheckBox.Value = true;
            app.RandomResizingCheckBox = uicheckbox(app.ModulationButtonGroup);
            app.RandomResizingCheckBox.Enable = 'off';
            app.RandomResizingCheckBox.Text = ' Random Resizing';
            app.RandomStrechingCheckBox = uicheckbox(app.ModulationButtonGroup);
            app.RandomStrechingCheckBox.Enable = 'off';
            app.RandomStrechingCheckBox.Text = ' Random Streching';
            app.DropDown_2 = uidropdown(app.ModulationButtonGroup);
            app.DropDown_2.Items = {'Image-Wide', 'Each Object'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'Image-Wide';
            app.VisualizeObjectButton = uibutton(app.ObjectControlsPanel, 'push');
            app.VisualizeObjectButton.Enable = 'off';
            app.VisualizeObjectButton.Text = 'Visualize Object';
            app.DiagnosticsPanel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.DiagnosticsPanel.Title = 'Diagnostics';
            app.ConditioningLamp = uilamp(app.DiagnosticsPanel);
            app.ConditioningofCostGaugeLabel = uilabel(app.DiagnosticsPanel);
            app.ConditioningofCostGaugeLabel.Text = 'Conditioning of Cost ';
            app.ConditioningofCostGauge = uigauge(app.DiagnosticsPanel, 'semicircular');
            app.CapacityLamp = uilamp(app.DiagnosticsPanel);
            app.NewObjectCapacityGaugeLabel = uilabel(app.DiagnosticsPanel);
            app.NewObjectCapacityGaugeLabel.Text = 'New Object Capacity';
            app.NewObjectCapacityGauge = uigauge(app.DiagnosticsPanel, 'semicircular');
            app.UIAxes = uiaxes(app.DiagnosticsPanel);
            title(app.UIAxes, 'Convergence to Target Statistics')
            ylabel(app.UIAxes, 'Error')
            app.GeneratorControlsPanel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.GeneratorControlsPanel.Title = 'Generator Controls';
            app.CostControlsPanel = uipanel(app.GeneratorControlsPanel);
            app.CostControlsPanel.Title = 'Cost Controls';
            app.SaveCostPresetButton = uibutton(app.CostControlsPanel, 'push');
            app.SaveCostPresetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCostPresetButtonPushed, true);
            app.SaveCostPresetButton.Text = 'Save Cost Preset';
            app.LoadCostPresetButton = uibutton(app.CostControlsPanel, 'push');
            app.LoadCostPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCostPresetButtonPushed, true);
            app.LoadCostPresetButton.Text = 'Load Cost Preset';
            app.CostAccumulationDropDownLabel = uilabel(app.CostControlsPanel);
            app.CostAccumulationDropDownLabel.Text = 'Cost Accumulation';
            app.CostAccumulationDropDown = uidropdown(app.CostControlsPanel);
            app.CostAccumulationDropDown.Items = {'Additive', 'Multiplicative'};
            app.CostAccumulationDropDown.Value = 'Multiplicative';
            app.PixelSwarmRefinementPanel = uipanel(app.GeneratorControlsPanel);
            app.PixelSwarmRefinementPanel.Title = 'Pixel Swarm Refinement';
            app.HeuristicDropDownLabel = uilabel(app.PixelSwarmRefinementPanel);
            app.HeuristicDropDownLabel.Enable = 'off';
            app.HeuristicDropDownLabel.Text = 'Heuristic';
            app.HeuristicDropDown = uidropdown(app.PixelSwarmRefinementPanel);
            app.HeuristicDropDown.Items = {'Thresholded Random', 'Weighted Random', 'Percentile Random'};
            app.HeuristicDropDown.Enable = 'off';
            app.HeuristicDropDown.Value = 'Weighted Random';
            app.IterationsEditFieldLabel = uilabel(app.PixelSwarmRefinementPanel);
            app.IterationsEditFieldLabel.Enable = 'off';
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.PixelSwarmRefinementPanel, 'numeric');
            app.IterationsEditField.Enable = 'off';
            app.IterationsEditField.Value = 10000;
            app.PixelSwarmPlay = uibutton(app.PixelSwarmRefinementPanel, 'push');
            app.PixelSwarmPlay.ButtonPushedFcn = createCallbackFcn(app, @PixelSwarmPlayButtonPushed, true);
            app.PixelSwarmPlay.Icon = 'play.png';
            app.PixelSwarmPlay.Enable = 'off';
            app.PixelSwarmPlay.Text = '';
            app.CleaningPanel = uipanel(app.GeneratorControlsPanel);
            app.CleaningPanel.Title = 'Cleaning';
            app.SmoothButton = uibutton(app.CleaningPanel, 'push');
            app.SmoothButton.Enable = 'off';
            app.SmoothButton.Text = 'Smooth';
            app.CloseGapsButton = uibutton(app.CleaningPanel, 'push');
            app.CloseGapsButton.Enable = 'off';
            app.CloseGapsButton.Text = 'Close Gaps';
            app.RemoveIslandsButton = uibutton(app.CleaningPanel, 'push');
            app.RemoveIslandsButton.Enable = 'off';
            app.RemoveIslandsButton.Text = 'Remove Islands';
            app.ScaleEditFieldLabel = uilabel(app.CleaningPanel);
            app.ScaleEditFieldLabel.Enable = 'off';
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.CleaningPanel, 'numeric');
            app.ScaleEditField.Enable = 'off';
            app.pixelsLabel = uilabel(app.CleaningPanel);
            app.pixelsLabel.Enable = 'off';
            app.pixelsLabel.Text = 'pixels';
            app.ObjectPlacementPanel = uipanel(app.GeneratorControlsPanel);
            app.ObjectPlacementPanel.Title = 'Object Placement';
            app.PlaceObjectPlay = uibutton(app.ObjectPlacementPanel, 'push');
            app.PlaceObjectPlay.ButtonPushedFcn = createCallbackFcn(app, @PlaceObjectPlayPushed, true);
            app.PlaceObjectPlay.Icon = 'play.png';
            app.PlaceObjectPlay.Text = '';
            app.HeuristicDropDown_2Label = uilabel(app.ObjectPlacementPanel);
            app.HeuristicDropDown_2Label.Text = 'Heuristic';
            app.HeuristicDropDown_2 = uidropdown(app.ObjectPlacementPanel);
            app.HeuristicDropDown_2.Items = {'Thresholded Random', 'Weighted Random', 'Percentile Random'};
            app.HeuristicDropDown_2.Value = 'Weighted Random';
            app.IterationsEditField_2Label = uilabel(app.ObjectPlacementPanel);
            app.IterationsEditField_2Label.Text = 'Iterations';
            app.IterationsEditField_2 = uieditfield(app.ObjectPlacementPanel, 'numeric');
            app.IterationsEditField_2.Value = 100;
            app.SaveNewImageButton = uibutton(app.GeneratorControlsPanel, 'push');
            app.SaveNewImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNewImageButtonPushed, true);
            app.SaveNewImageButton.Icon = 'save.png';
            app.SaveNewImageButton.Text = 'Save New Image';
            app.VisualizationSettingsButtonGroup = uibuttongroup(app.GeneratorControlsPanel);
            app.VisualizationSettingsButtonGroup.Title = 'Visualization Settings';
            app.AfterEachObjectSlowButton = uiradiobutton(app.VisualizationSettingsButtonGroup);
            app.AfterEachObjectSlowButton.Text = 'After Each Object (Slow)';
            app.AfterEachObjectSlowButton.Value = true;
            app.AfterEachStageFastButton = uiradiobutton(app.VisualizationSettingsButtonGroup);
            app.AfterEachStageFastButton.Text = 'After Each Stage (Fast)';
            app.UpdateCostButton = uibutton(app.GeneratorControlsPanel, 'push');
            app.UpdateCostButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCostButtonPushed, true);
            app.UpdateCostButton.Icon = 'update.png';
            app.UpdateCostButton.Text = 'Update Cost';
            app.CostFunction5Panel = uipanel(app.MicrostructureGeneratorUIFigure);
            app.CostFunction5Panel.Title = 'Cost Function #5';
            app.Switch_5 = uiswitch(app.CostFunction5Panel, 'slider');
            app.Switch_5.Items = {'On', 'Off'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.MinEditField_5Label = uilabel(app.CostFunction5Panel);
            app.MinEditField_5Label.Enable = 'off';
            app.MinEditField_5Label.Text = 'Min';
            app.MinEditField_5 = uieditfield(app.CostFunction5Panel, 'numeric');
            app.MinEditField_5.Enable = 'off';
            app.MaxEditField_5Label = uilabel(app.CostFunction5Panel);
            app.MaxEditField_5Label.Enable = 'off';
            app.MaxEditField_5Label.Text = 'Max';
            app.MaxEditField_5 = uieditfield(app.CostFunction5Panel, 'numeric');
            app.MaxEditField_5.Enable = 'off';
            app.TaperDropDown_5Label = uilabel(app.CostFunction5Panel);
            app.TaperDropDown_5Label.Enable = 'off';
            app.TaperDropDown_5Label.Text = 'Taper';
            app.TaperDropDown_5 = uidropdown(app.CostFunction5Panel);
            app.TaperDropDown_5.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown_5.ValueChangedFcn = createCallbackFcn(app, @TaperDropDown_5ValueChanged, true);
            app.TaperDropDown_5.Enable = 'off';
            app.TaperDropDown_5.Value = 'Gaussian';
            app.EditField_6Label = uilabel(app.CostFunction5Panel);
            app.EditField_6Label.Enable = 'off';
            app.EditField_6Label.Text = 'σ';
            app.EditField_6 = uieditfield(app.CostFunction5Panel, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.Enable = 'off';
            app.EditField_6.Value = 1;
            app.Label_6 = uilabel(app.CostFunction5Panel);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '%';
            app.ObjectStateLabelDropDown_5Label = uilabel(app.CostFunction5Panel);
            app.ObjectStateLabelDropDown_5Label.Enable = 'off';
            app.ObjectStateLabelDropDown_5Label.Text = 'Object State Label';
            app.ObjectStateLabelDropDown_5 = uidropdown(app.CostFunction5Panel);
            app.ObjectStateLabelDropDown_5.Enable = 'off';
            app.ObjectiveDropDown_5Label = uilabel(app.CostFunction5Panel);
            app.ObjectiveDropDown_5Label.Enable = 'off';
            app.ObjectiveDropDown_5Label.Text = 'Objective:';
            app.ObjectiveDropDown_5 = uidropdown(app.CostFunction5Panel);
            app.ObjectiveDropDown_5.Items = {'Overlap', 'Seperation'};
            app.ObjectiveDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDown_5ValueChanged, true);
            app.ObjectiveDropDown_5.Enable = 'off';
            app.ObjectiveDropDown_5.Value = 'Overlap';
            app.Button_5 = uibutton(app.CostFunction5Panel, 'push');
            app.Button_5.Icon = 'overlap.png';
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '';
            app.ImageStateLabelDropDown_5Label = uilabel(app.CostFunction5Panel);
            app.ImageStateLabelDropDown_5Label.Enable = 'off';
            app.ImageStateLabelDropDown_5Label.Text = 'Image State Label';
            app.ImageStateLabelDropDown_5 = uidropdown(app.CostFunction5Panel);
            app.ImageStateLabelDropDown_5.Enable = 'off';
            app.LoadTargetButton_5 = uibutton(app.CostFunction5Panel, 'push');
            app.LoadTargetButton_5.Enable = 'off';
            app.LoadTargetButton_5.Text = 'Load Target';
            app.RangeLabel_5 = uilabel(app.CostFunction5Panel);
            app.RangeLabel_5.Enable = 'off';
            app.RangeLabel_5.Text = 'Range:';
            app.CheckBox_5 = uicheckbox(app.CostFunction5Panel);
            app.CheckBox_5.Enable = 'off';
            app.CheckBox_5.Text = '';
            app.FullLabel_5 = uilabel(app.CostFunction5Panel);
            app.FullLabel_5.Enable = 'off';
            app.FullLabel_5.Text = 'Full';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Generator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrostructureGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrostructureGeneratorUIFigure)
        end
    end
end