classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CostFunction1Panel              matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        TaperDropDownLabel              matlab.ui.control.Label
        TaperDropDown                   matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        ObjectStateLabelDropDownLabel   matlab.ui.control.Label
        ObjectStateLabelDropDown        matlab.ui.control.DropDown
        ObjectiveDropDownLabel          matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        ImageStateLabelDropDownLabel    matlab.ui.control.Label
        ImageStateLabelDropDown         matlab.ui.control.DropDown
        LoadTargetButton                matlab.ui.control.Button
        RangeLabel                      matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        FullLabel                       matlab.ui.control.Label
        LoadImageButton                 matlab.ui.control.Button
        LoadObjectButton                matlab.ui.control.Button
        UpdateCostFunctionsButton       matlab.ui.control.Button
        GaugeLabel                      matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        CostFunction2Panel              matlab.ui.container.Panel
        Switch_2                        matlab.ui.control.Switch
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        TaperDropDown_2Label            matlab.ui.control.Label
        TaperDropDown_2                 matlab.ui.control.DropDown
        EditField_3Label                matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        ObjectStateLabelDropDown_2Label  matlab.ui.control.Label
        ObjectStateLabelDropDown_2      matlab.ui.control.DropDown
        ObjectiveDropDown_2Label        matlab.ui.control.Label
        ObjectiveDropDown_2             matlab.ui.control.DropDown
        Button_2                        matlab.ui.control.Button
        ImageStateLabelDropDown_2Label  matlab.ui.control.Label
        ImageStateLabelDropDown_2       matlab.ui.control.DropDown
        LoadTargetButton_2              matlab.ui.control.Button
        RangeLabel_2                    matlab.ui.control.Label
        CheckBox_2                      matlab.ui.control.CheckBox
        FullLabel_2                     matlab.ui.control.Label
        CostFunction3Panel              matlab.ui.container.Panel
        Switch_3                        matlab.ui.control.Switch
        MinEditField_3Label             matlab.ui.control.Label
        MinEditField_3                  matlab.ui.control.NumericEditField
        MaxEditField_3Label             matlab.ui.control.Label
        MaxEditField_3                  matlab.ui.control.NumericEditField
        TaperDropDown_3Label            matlab.ui.control.Label
        TaperDropDown_3                 matlab.ui.control.DropDown
        EditField_4Label                matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        ObjectStateLabelDropDown_3Label  matlab.ui.control.Label
        ObjectStateLabelDropDown_3      matlab.ui.control.DropDown
        ObjectiveDropDown_3Label        matlab.ui.control.Label
        ObjectiveDropDown_3             matlab.ui.control.DropDown
        Button_3                        matlab.ui.control.Button
        ImageStateLabelDropDown_3Label  matlab.ui.control.Label
        ImageStateLabelDropDown_3       matlab.ui.control.DropDown
        LoadTargetButton_3              matlab.ui.control.Button
        RangeLabel_3                    matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        FullLabel_3                     matlab.ui.control.Label
        CostFunction4Panel              matlab.ui.container.Panel
        Switch_4                        matlab.ui.control.Switch
        MinEditField_4Label             matlab.ui.control.Label
        MinEditField_4                  matlab.ui.control.NumericEditField
        MaxEditField_4Label             matlab.ui.control.Label
        MaxEditField_4                  matlab.ui.control.NumericEditField
        TaperDropDown_4Label            matlab.ui.control.Label
        TaperDropDown_4                 matlab.ui.control.DropDown
        EditField_5Label                matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        ObjectStateLabelDropDown_4Label  matlab.ui.control.Label
        ObjectStateLabelDropDown_4      matlab.ui.control.DropDown
        ObjectiveDropDown_4Label        matlab.ui.control.Label
        ObjectiveDropDown_4             matlab.ui.control.DropDown
        Button_4                        matlab.ui.control.Button
        ImageStateLabelDropDown_4Label  matlab.ui.control.Label
        ImageStateLabelDropDown_4       matlab.ui.control.DropDown
        LoadTargetButton_4              matlab.ui.control.Button
        RangeLabel_4                    matlab.ui.control.Label
        CheckBox_4                      matlab.ui.control.CheckBox
        FullLabel_4                     matlab.ui.control.Label
    end


    properties (Access = private)
        % UIVisualTrackers
        CostFunction1PanelEnableValues = cell(21,1);
        CostFunction2PanelEnableValues = cell(21,1);
        CostFunction3PanelEnableValues = cell(21,1);
        CostFunction4PanelEnableValues = cell(21,1);
        CFpanel1uilist = [];
        CFpanel2uilist = [];
        CFpanel3uilist = [];
        CFpanel4uilist = [];
        
        % Main
        Image = [];
        ImageStates = [];
        Object = [];
        ObjectStates =[];


        Constraints = cell(4,1);
        
        
        % 2D Test
        TestImage2D = [];
        TestObject2D = [];


        ObjectLib = [];
        Target = [];

    end

    methods (Access = private)
    
        function Constraint = Panel2Constraint(app,panelnumber)
            
            panelfields = findall(app.(['CostFunction',num2str(panelnumber),'Panel']).Children, '-property', 'value');
            
            useflag = strcmp(panelfields(9).Value,'On');
            
            switch panelfields(3).Value
                case 'Overlap'
                    costtype = 'overlap';
                case 'Seperation'
                    costtype = 'seperation';
                case '2pt Statistics'
                    costtype = 'spatialstats';
            end
            
            if ~isnumeric(str2double(panelfields(2).Value)) && ~strcmp(panelfields(2).Value,'Non-zero')
                errordlg('Load Image First!')
                useflag = false;
                imagelabel = 0;
            elseif strcmp(panelfields(2).Value,'Non-zero')
                imagelabel = 'full';
            else
                imagelabel = str2double(panelfields(2).Value);
            end
            
            if ~isnumeric(str2double(panelfields(4).Value)) && ~strcmp(panelfields(4).Value,'Non-zero')
                errordlg('Load Object First!');
                useflag = false;
                objectlabel = 0;
            elseif strcmp(panelfields(4).Value,'Non-zero')
                objectlabel = 'full';
            else
                objectlabel = str2double(panelfields(4).Value);
            end
            
            if panelfields(1).Value
                range = 'full';
            else
                range = [panelfields(8).Value panelfields(7).Value];
            end
            
            switch panelfields(6).Value
                case 'Gaussian'
                    taper = 'gaussian';
                    relaxation = panelfields(5).Value;
                case 'Absolute'
                    taper = 'absolute';
                    relaxation = 0;
            end
            
            Constraint = ObjectPlacementConstraint(useflag, costtype, imagelabel, objectlabel, range, taper, relaxation);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Panel 1
            app.CostFunction1PanelEnableValues(:) = {'on'};
            app.CostFunction1PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel1uilist = findall(app.CostFunction1Panel.Children, '-property', 'enable');
            
            % Panel 2
            app.CostFunction2PanelEnableValues(:) = {'on'};
            app.CostFunction2PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel2uilist = findall(app.CostFunction2Panel.Children, '-property', 'enable');
            
            % Panel 3
            app.CostFunction3PanelEnableValues(:) = {'on'};
            app.CostFunction3PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel3uilist = findall(app.CostFunction3Panel.Children, '-property', 'enable');
            
            % Panel 4
            app.CostFunction4PanelEnableValues(:) = {'on'};
            app.CostFunction4PanelEnableValues([1,2,4]) = {'off'}; 
            app.CFpanel4uilist = findall(app.CostFunction4Panel.Children, '-property', 'enable');
            
            % Test 2D
            S = load('Image.mat');
            Snames = fieldnames(S);
            app.TestImage2D = S.(Snames{1});
            
            S = load('Object.mat');
            Snames = fieldnames(S);
            app.TestObject2D = S.(Snames{1});
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 1
                app.CostFunction1PanelEnableValues([3,12,17:20]) = {'off'};
                for i=1:length(app.CFpanel1uilist)
                    app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                end
            elseif value == 0
                app.CostFunction1PanelEnableValues([3,12,17:20]) = {'on'};
                for i=1:length(app.CFpanel1uilist)
                    app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                end
            end             
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            S = uiimport('-file');
            Snames = fieldnames(S);
            app.Image = S.(Snames{1});
            app.ImageStates = unique(app.Image);
            statelist = cellstr(string(unique(app.Image)));
            statelist{end+1} = 'Non-zero';
            app.ImageStateLabelDropDown.Items = statelist;           
            app.ImageStateLabelDropDown_2.Items = statelist;
            app.ImageStateLabelDropDown_3.Items = statelist;
            app.ImageStateLabelDropDown_4.Items = statelist;
        end

        % Button pushed function: LoadObjectButton
        function LoadObjectButtonPushed(app, event)
            S = uiimport('-file');
            Snames = fieldnames(S);
            app.Object = S.(Snames{1});
            app.ObjectStates = unique(app.Object);
            statelist = cellstr(string(unique(app.Object)));
            statelist{end+1} = 'Non-zero';
            app.ObjectStateLabelDropDown.Items = statelist;
            app.ObjectStateLabelDropDown_2.Items = statelist;
            app.ObjectStateLabelDropDown_3.Items = statelist;
            app.ObjectStateLabelDropDown_4.Items = statelist;
        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveDropDownValueChanged(app, event)
            value = app.ObjectiveDropDown.Value;
            switch value
                case 'Overlap'
                    app.CostFunction1PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction1PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel1uilist(12).Text = '%';
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction1PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction1PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel1uilist(12).Text = 'pixels';
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
                case '2pt Statistics'
                    app.CostFunction1PanelEnableValues([1,2,4]) = {'on'};
                    app.CheckBox.Value = 1;
                    app.CostFunction1PanelEnableValues([3,12,17:20]) = {'off'};
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
            end
                    
        end

        % Value changed function: ObjectiveDropDown_2
        function ObjectiveDropDown_2ValueChanged(app, event)
            value = app.ObjectiveDropDown_2.Value;
            switch value
                case 'Overlap'
                    app.CostFunction2PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction2PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel2uilist(12).Text = '%';
                    for i=1:length(app.CFpanel2uilist)
                        app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction2PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction2PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel2uilist(12).Text = 'pixels';
                    for i=1:length(app.CFpanel2uilist)
                        app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                    end
            %                 case '2pt Statistics'
            %                     app.CostFunction2PanelEnableValues([1,2,4]) = {'on'};
            %                     app.CheckBox_2.Value = 1;
            %                     app.CostFunction2PanelEnableValues([3,12,17:20]) = {'off'};
            %                     for i=1:length(app.CFpanel2uilist)
            %                         app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
            %                     end
            end
            
        end

        % Value changed function: ObjectiveDropDown_3
        function ObjectiveDropDown_3ValueChanged(app, event)
            value = app.ObjectiveDropDown_3.Value;
            switch value
                case 'Overlap'
                    app.CostFunction3PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction3PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel3uilist(12).Text = '%';
                    for i=1:length(app.CFpanel3uilist)
                        app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction3PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction3PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel3uilist(12).Text = 'pixels';
                    for i=1:length(app.CFpanel3uilist)
                        app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                    end
            %                 case '2pt Statistics'
            %                     app.CostFunction3PanelEnableValues([1,2,4]) = {'on'};
            %                     app.CheckBox_3.Value = 1;
            %                     app.CostFunction3PanelEnableValues([3,12,17:20]) = {'off'};
            %                     for i=1:length(app.CFpanel3uilist)
            %                         app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
            %                     end
            end
            
        end

        % Value changed function: ObjectiveDropDown_4
        function ObjectiveDropDown_4ValueChanged(app, event)
            value = app.ObjectiveDropDown_4.Value;
            switch value
                case 'Overlap'
                    app.CostFunction4PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction4PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel4uilist(12).Text = '%';
                    for i=1:length(app.CFpanel4uilist)
                        app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                    end
                case 'Seperation'
                    app.CostFunction4PanelEnableValues([1,2,4]) = {'off'};
                    app.CostFunction4PanelEnableValues([3,12,17:20]) = {'on'};
                    app.CFpanel4uilist(12).Text = 'pixels';
                    for i=1:length(app.CFpanel4uilist)
                        app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                    end
            %                 case '2pt Statistics'
            %                     app.CostFunction4PanelEnableValues([1,2,4]) = {'on'};
            %                     app.CheckBox_4.Value = 1;
            %                     app.CostFunction4PanelEnableValues([3,12,17:20]) = {'off'};
            %                     for i=1:length(app.CFpanel4uilist)
            %                         app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
            %                     end
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                set(app.CFpanel1uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel1uilist)
                    app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                end
            end
            app.Switch.Enable = 'on';
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if strcmp(value,'Off')
                set(app.CFpanel2uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel2uilist)
                    app.CFpanel2uilist(i).Enable = app.CostFunction2PanelEnableValues{i};
                end
            end
            app.Switch_2.Enable = 'on';
            
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            if strcmp(value,'Off')
                set(app.CFpanel3uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel3uilist)
                    app.CFpanel3uilist(i).Enable = app.CostFunction3PanelEnableValues{i};
                end
            end
            app.Switch_3.Enable = 'on';
            
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if strcmp(value,'Off')
                set(app.CFpanel4uilist, 'enable', value)
            else
                for i=1:length(app.CFpanel4uilist)
                    app.CFpanel4uilist(i).Enable = app.CostFunction4PanelEnableValues{i};
                end
            end
            app.Switch_4.Enable = 'on';
            
        end

        % Value changed function: TaperDropDown
        function TaperDropDownValueChanged(app, event)
            value = app.TaperDropDown.Value;
            switch value
                case 'Gaussian'
                    app.CostFunction1PanelEnableValues([14,13]) = {'on'};
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
                case 'Absolute'
                    app.CostFunction1PanelEnableValues([14,13]) = {'off'};
                    for i=1:length(app.CFpanel1uilist)
                        app.CFpanel1uilist(i).Enable = app.CostFunction1PanelEnableValues{i};
                    end
            end
        end

        % Button pushed function: UpdateCostFunctionsButton
        function UpdateCostFunctionsButtonPushed(app, event)
            for ii = 1:4
                app.Constraints{ii} = Panel2Constraint(app,ii);
            end
            
            if ismatrix(app.Image)
               costmap = CompileCostFunctions(app.TestImage2D ,app.TestObject2D ,0, app.Constraints,'times');
            elseif ndims(app.Image) == 3
               costmap = CompileCostFunctions(app.TestImage3D ,app.TestObject3D ,target, app.Constraints,'times');
            end
            
            Conditioning = mean(costmap(costmap >= prctile(costmap(:),95)));
            app.Gauge.Value = Conditioning*100;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CostFunction1Panel = uipanel(app.UIFigure);
            app.CostFunction1Panel.Title = 'Cost Function #1';
            app.Switch = uiswitch(app.CostFunction1Panel, 'slider');
            app.Switch.Items = {'On', 'Off'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.CostFunction1Panel);
            app.MinEditFieldLabel.Enable = 'off';
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.CostFunction1Panel, 'numeric');
            app.MinEditField.Enable = 'off';
            app.MaxEditFieldLabel = uilabel(app.CostFunction1Panel);
            app.MaxEditFieldLabel.Enable = 'off';
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.CostFunction1Panel, 'numeric');
            app.MaxEditField.Enable = 'off';
            app.TaperDropDownLabel = uilabel(app.CostFunction1Panel);
            app.TaperDropDownLabel.Enable = 'off';
            app.TaperDropDownLabel.Text = 'Taper';
            app.TaperDropDown = uidropdown(app.CostFunction1Panel);
            app.TaperDropDown.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown.ValueChangedFcn = createCallbackFcn(app, @TaperDropDownValueChanged, true);
            app.TaperDropDown.Enable = 'off';
            app.TaperDropDown.Value = 'Gaussian';
            app.Label = uilabel(app.CostFunction1Panel);
            app.Label.Enable = 'off';
            app.Label.Text = 'σ';
            app.EditField_2 = uieditfield(app.CostFunction1Panel, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 1;
            app.Label_2 = uilabel(app.CostFunction1Panel);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '%';
            app.ObjectStateLabelDropDownLabel = uilabel(app.CostFunction1Panel);
            app.ObjectStateLabelDropDownLabel.Enable = 'off';
            app.ObjectStateLabelDropDownLabel.Text = 'Object State Label';
            app.ObjectStateLabelDropDown = uidropdown(app.CostFunction1Panel);
            app.ObjectStateLabelDropDown.Enable = 'off';
            app.ObjectiveDropDownLabel = uilabel(app.CostFunction1Panel);
            app.ObjectiveDropDownLabel.Enable = 'off';
            app.ObjectiveDropDownLabel.Text = 'Objective:';
            app.ObjectiveDropDown = uidropdown(app.CostFunction1Panel);
            app.ObjectiveDropDown.Items = {'Overlap', 'Seperation', '2pt Statistics'};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDownValueChanged, true);
            app.ObjectiveDropDown.Enable = 'off';
            app.ObjectiveDropDown.Value = 'Overlap';
            app.Button = uibutton(app.CostFunction1Panel, 'push');
            app.Button.Icon = '24432_1413979407.jpeg';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.ImageStateLabelDropDownLabel = uilabel(app.CostFunction1Panel);
            app.ImageStateLabelDropDownLabel.Enable = 'off';
            app.ImageStateLabelDropDownLabel.Text = 'Image State Label';
            app.ImageStateLabelDropDown = uidropdown(app.CostFunction1Panel);
            app.ImageStateLabelDropDown.Enable = 'off';
            app.LoadTargetButton = uibutton(app.CostFunction1Panel, 'push');
            app.LoadTargetButton.Enable = 'off';
            app.LoadTargetButton.Text = 'Load Target';
            app.RangeLabel = uilabel(app.CostFunction1Panel);
            app.RangeLabel.Enable = 'off';
            app.RangeLabel.Text = 'Range:';
            app.CheckBox = uicheckbox(app.CostFunction1Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.FullLabel = uilabel(app.CostFunction1Panel);
            app.FullLabel.Enable = 'off';
            app.FullLabel.Text = 'Full';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadObjectButton = uibutton(app.UIFigure, 'push');
            app.LoadObjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadObjectButtonPushed, true);
            app.LoadObjectButton.Text = 'Load Object';
            app.UpdateCostFunctionsButton = uibutton(app.UIFigure, 'push');
            app.UpdateCostFunctionsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCostFunctionsButtonPushed, true);
            app.UpdateCostFunctionsButton.Text = 'Update Cost Functions';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.CostFunction2Panel = uipanel(app.UIFigure);
            app.CostFunction2Panel.Title = 'Cost Function #2';
            app.Switch_2 = uiswitch(app.CostFunction2Panel, 'slider');
            app.Switch_2.Items = {'On', 'Off'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.MinEditField_2Label = uilabel(app.CostFunction2Panel);
            app.MinEditField_2Label.Enable = 'off';
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.CostFunction2Panel, 'numeric');
            app.MinEditField_2.Enable = 'off';
            app.MaxEditField_2Label = uilabel(app.CostFunction2Panel);
            app.MaxEditField_2Label.Enable = 'off';
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.CostFunction2Panel, 'numeric');
            app.MaxEditField_2.Enable = 'off';
            app.TaperDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.TaperDropDown_2Label.Enable = 'off';
            app.TaperDropDown_2Label.Text = 'Taper';
            app.TaperDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.TaperDropDown_2.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown_2.Enable = 'off';
            app.TaperDropDown_2.Value = 'Gaussian';
            app.EditField_3Label = uilabel(app.CostFunction2Panel);
            app.EditField_3Label.Enable = 'off';
            app.EditField_3Label.Text = 'σ';
            app.EditField_3 = uieditfield(app.CostFunction2Panel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = 1;
            app.Label_3 = uilabel(app.CostFunction2Panel);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '%';
            app.ObjectStateLabelDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.ObjectStateLabelDropDown_2Label.Enable = 'off';
            app.ObjectStateLabelDropDown_2Label.Text = 'Object State Label';
            app.ObjectStateLabelDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.ObjectStateLabelDropDown_2.Enable = 'off';
            app.ObjectiveDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.ObjectiveDropDown_2Label.Enable = 'off';
            app.ObjectiveDropDown_2Label.Text = 'Objective:';
            app.ObjectiveDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.ObjectiveDropDown_2.Items = {'Overlap', 'Seperation'};
            app.ObjectiveDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDown_2ValueChanged, true);
            app.ObjectiveDropDown_2.Enable = 'off';
            app.ObjectiveDropDown_2.Value = 'Overlap';
            app.Button_2 = uibutton(app.CostFunction2Panel, 'push');
            app.Button_2.Icon = '24432_1413979407.jpeg';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.ImageStateLabelDropDown_2Label = uilabel(app.CostFunction2Panel);
            app.ImageStateLabelDropDown_2Label.Enable = 'off';
            app.ImageStateLabelDropDown_2Label.Text = 'Image State Label';
            app.ImageStateLabelDropDown_2 = uidropdown(app.CostFunction2Panel);
            app.ImageStateLabelDropDown_2.Enable = 'off';
            app.LoadTargetButton_2 = uibutton(app.CostFunction2Panel, 'push');
            app.LoadTargetButton_2.Enable = 'off';
            app.LoadTargetButton_2.Text = 'Load Target';
            app.RangeLabel_2 = uilabel(app.CostFunction2Panel);
            app.RangeLabel_2.Enable = 'off';
            app.RangeLabel_2.Text = 'Range:';
            app.CheckBox_2 = uicheckbox(app.CostFunction2Panel);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.FullLabel_2 = uilabel(app.CostFunction2Panel);
            app.FullLabel_2.Enable = 'off';
            app.FullLabel_2.Text = 'Full';
            app.CostFunction3Panel = uipanel(app.UIFigure);
            app.CostFunction3Panel.Title = 'Cost Function #3';
            app.Switch_3 = uiswitch(app.CostFunction3Panel, 'slider');
            app.Switch_3.Items = {'On', 'Off'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.MinEditField_3Label = uilabel(app.CostFunction3Panel);
            app.MinEditField_3Label.Enable = 'off';
            app.MinEditField_3Label.Text = 'Min';
            app.MinEditField_3 = uieditfield(app.CostFunction3Panel, 'numeric');
            app.MinEditField_3.Enable = 'off';
            app.MaxEditField_3Label = uilabel(app.CostFunction3Panel);
            app.MaxEditField_3Label.Enable = 'off';
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.CostFunction3Panel, 'numeric');
            app.MaxEditField_3.Enable = 'off';
            app.TaperDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.TaperDropDown_3Label.Enable = 'off';
            app.TaperDropDown_3Label.Text = 'Taper';
            app.TaperDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.TaperDropDown_3.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown_3.Enable = 'off';
            app.TaperDropDown_3.Value = 'Gaussian';
            app.EditField_4Label = uilabel(app.CostFunction3Panel);
            app.EditField_4Label.Enable = 'off';
            app.EditField_4Label.Text = 'σ';
            app.EditField_4 = uieditfield(app.CostFunction3Panel, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.Enable = 'off';
            app.EditField_4.Value = 1;
            app.Label_4 = uilabel(app.CostFunction3Panel);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '%';
            app.ObjectStateLabelDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.ObjectStateLabelDropDown_3Label.Enable = 'off';
            app.ObjectStateLabelDropDown_3Label.Text = 'Object State Label';
            app.ObjectStateLabelDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.ObjectStateLabelDropDown_3.Enable = 'off';
            app.ObjectiveDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.ObjectiveDropDown_3Label.Enable = 'off';
            app.ObjectiveDropDown_3Label.Text = 'Objective:';
            app.ObjectiveDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.ObjectiveDropDown_3.Items = {'Overlap', 'Seperation'};
            app.ObjectiveDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDown_3ValueChanged, true);
            app.ObjectiveDropDown_3.Enable = 'off';
            app.ObjectiveDropDown_3.Value = 'Overlap';
            app.Button_3 = uibutton(app.CostFunction3Panel, 'push');
            app.Button_3.Icon = '24432_1413979407.jpeg';
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.ImageStateLabelDropDown_3Label = uilabel(app.CostFunction3Panel);
            app.ImageStateLabelDropDown_3Label.Enable = 'off';
            app.ImageStateLabelDropDown_3Label.Text = 'Image State Label';
            app.ImageStateLabelDropDown_3 = uidropdown(app.CostFunction3Panel);
            app.ImageStateLabelDropDown_3.Enable = 'off';
            app.LoadTargetButton_3 = uibutton(app.CostFunction3Panel, 'push');
            app.LoadTargetButton_3.Enable = 'off';
            app.LoadTargetButton_3.Text = 'Load Target';
            app.RangeLabel_3 = uilabel(app.CostFunction3Panel);
            app.RangeLabel_3.Enable = 'off';
            app.RangeLabel_3.Text = 'Range:';
            app.CheckBox_3 = uicheckbox(app.CostFunction3Panel);
            app.CheckBox_3.Enable = 'off';
            app.CheckBox_3.Text = '';
            app.FullLabel_3 = uilabel(app.CostFunction3Panel);
            app.FullLabel_3.Enable = 'off';
            app.FullLabel_3.Text = 'Full';
            app.CostFunction4Panel = uipanel(app.UIFigure);
            app.CostFunction4Panel.Title = 'Cost Function #4';
            app.Switch_4 = uiswitch(app.CostFunction4Panel, 'slider');
            app.Switch_4.Items = {'On', 'Off'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.MinEditField_4Label = uilabel(app.CostFunction4Panel);
            app.MinEditField_4Label.Enable = 'off';
            app.MinEditField_4Label.Text = 'Min';
            app.MinEditField_4 = uieditfield(app.CostFunction4Panel, 'numeric');
            app.MinEditField_4.Enable = 'off';
            app.MaxEditField_4Label = uilabel(app.CostFunction4Panel);
            app.MaxEditField_4Label.Enable = 'off';
            app.MaxEditField_4Label.Text = 'Max';
            app.MaxEditField_4 = uieditfield(app.CostFunction4Panel, 'numeric');
            app.MaxEditField_4.Enable = 'off';
            app.TaperDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.TaperDropDown_4Label.Enable = 'off';
            app.TaperDropDown_4Label.Text = 'Taper';
            app.TaperDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.TaperDropDown_4.Items = {'Gaussian', 'Absolute'};
            app.TaperDropDown_4.Enable = 'off';
            app.TaperDropDown_4.Value = 'Gaussian';
            app.EditField_5Label = uilabel(app.CostFunction4Panel);
            app.EditField_5Label.Enable = 'off';
            app.EditField_5Label.Text = 'σ';
            app.EditField_5 = uieditfield(app.CostFunction4Panel, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.Enable = 'off';
            app.EditField_5.Value = 1;
            app.Label_5 = uilabel(app.CostFunction4Panel);
            app.Label_5.Enable = 'off';
            app.Label_5.Text = '%';
            app.ObjectStateLabelDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.ObjectStateLabelDropDown_4Label.Enable = 'off';
            app.ObjectStateLabelDropDown_4Label.Text = 'Object State Label';
            app.ObjectStateLabelDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.ObjectStateLabelDropDown_4.Enable = 'off';
            app.ObjectiveDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.ObjectiveDropDown_4Label.Enable = 'off';
            app.ObjectiveDropDown_4Label.Text = 'Objective:';
            app.ObjectiveDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.ObjectiveDropDown_4.Items = {'Overlap', 'Seperation'};
            app.ObjectiveDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDown_4ValueChanged, true);
            app.ObjectiveDropDown_4.Enable = 'off';
            app.ObjectiveDropDown_4.Value = 'Overlap';
            app.Button_4 = uibutton(app.CostFunction4Panel, 'push');
            app.Button_4.Icon = '24432_1413979407.jpeg';
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '';
            app.ImageStateLabelDropDown_4Label = uilabel(app.CostFunction4Panel);
            app.ImageStateLabelDropDown_4Label.Enable = 'off';
            app.ImageStateLabelDropDown_4Label.Text = 'Image State Label';
            app.ImageStateLabelDropDown_4 = uidropdown(app.CostFunction4Panel);
            app.ImageStateLabelDropDown_4.Enable = 'off';
            app.LoadTargetButton_4 = uibutton(app.CostFunction4Panel, 'push');
            app.LoadTargetButton_4.Enable = 'off';
            app.LoadTargetButton_4.Text = 'Load Target';
            app.RangeLabel_4 = uilabel(app.CostFunction4Panel);
            app.RangeLabel_4.Enable = 'off';
            app.RangeLabel_4.Text = 'Range:';
            app.CheckBox_4 = uicheckbox(app.CostFunction4Panel);
            app.CheckBox_4.Enable = 'off';
            app.CheckBox_4.Text = '';
            app.FullLabel_4 = uilabel(app.CostFunction4Panel);
            app.FullLabel_4.Enable = 'off';
            app.FullLabel_4.Text = 'Full';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end